classdef demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu_2                        matlab.ui.container.Menu
        DetectionMethodDropDownLabel  matlab.ui.control.Label
        DetectionMethodDropDown       matlab.ui.control.DropDown
        estimateGeometricTransformCheckBox  matlab.ui.control.CheckBox
        FindKeypointsButton           matlab.ui.control.Button
        ReferenceImage                matlab.ui.control.UIAxes
        TargetImage                   matlab.ui.control.UIAxes
        ResultImage                   matlab.ui.control.UIAxes
        MatchKeypointsButton          matlab.ui.control.Button
        ToggleWebcamButtonTarget      matlab.ui.control.StateButton
        ToggleWebcamButtonReference   matlab.ui.control.StateButton
        ReferenceImageMenuButton      matlab.ui.control.Button
        TargetImageMenuButton         matlab.ui.control.Button
        GaugeLabel                    matlab.ui.control.Label
        Gauge                         matlab.ui.control.NinetyDegreeGauge
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Lamp2Label                    matlab.ui.control.Label
        Lamp2                         matlab.ui.control.Lamp
        SwitchLabel                   matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        ContextMenu                   matlab.ui.container.ContextMenu
        SelectImageMenu               matlab.ui.container.Menu
        SaveImageMenu                 matlab.ui.container.Menu
        CropImageMenu                 matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        SelectImageMenu_2             matlab.ui.container.Menu
        SaveImageMenu_2               matlab.ui.container.Menu
        CropImageMenu_2               matlab.ui.container.Menu
    end

    
    properties (Access = private)
        reference_image_data;
        target_image_data;
        
        reference_image_data_grey
        target_image_data_grey
        
        ref_im_object
        tar_im_object
        
        referencePoints
        targetPoints
        
        cam;
        camActive = false;
        
        
    end
    
    methods (Access = private)
      
        function result = handle_webcam(app, target, state)
            result = [];
            if(isempty(app.cam))
                try
                    app.cam = webcam(1);
                catch ME
                    msgbox("No Webcam found!");
                    return;
                end
                
            end
            
            if(state == 1)
                frame = snapshot(app.cam);
                preview(app.cam, image(target,frame));
                result = frame;
            else
                result = snapshot(app.cam);
                closePreview(app.cam);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'DefaultFigureVisible','off');
        end

        % Menu selected function: SelectImageMenu
        function SelectReferenceButtonPushed(app, event)
            [reference_file,reference_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(reference_file,0)
                return;
            else
                app.reference_image_data = imread(fullfile(reference_path, reference_file));
                imshow(app.reference_image_data,'Parent',app.ReferenceImage);
                cla(app.ResultImage);
                figure(app.UIFigure);
            end
        end

        % Menu selected function: SelectImageMenu_2
        function SelectTargetButtonPushed(app, event)
            [target_file,target_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(target_file,0)
                return;
            else
                app.target_image_data = imread(fullfile(target_path, target_file));
                imshow(app.target_image_data,'Parent',app.TargetImage);
                cla(app.ResultImage);
                figure(app.UIFigure);
            end
        end

        % Button pushed function: FindKeypointsButton
        function FindKeypointsButtonPushed(app, event)
            app.ToggleWebcamButtonReference.Value = false;
            if(isempty(app.reference_image_data)) 
                msgbox("Reference Image is missing");
                return
            end
            if (isempty(app.target_image_data))
                msgbox("Target Image is missing");
                return
            end
           
            cla(app.ReferenceImage)
            cla(app.TargetImage)
            imshow(app.target_image_data,'Parent',app.TargetImage);
            imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            app.reference_image_data_grey = rgb2gray(app.reference_image_data);
            app.target_image_data_grey = rgb2gray(app.target_image_data);
            
            cla(app.ResultImage);
            
            switch app.DetectionMethodDropDown.Value
                case "BRISK"
                    app.referencePoints = detectBRISKFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectBRISKFeatures(app.target_image_data_grey);
                case "FAST"
                    app.referencePoints = detectFASTFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectFASTFeatures(app.target_image_data_grey);
                case "Harris"
                    app.referencePoints = detectHarrisFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectHarrisFeatures(app.target_image_data_grey);
                case "MinEigen"
                    app.referencePoints = detectMinEigenFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectMinEigenFeatures(app.target_image_data_grey);
                case "ORB"
                    app.referencePoints = detectORBFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectORBFeatures(app.target_image_data_grey);
                case "SURF"
                    app.referencePoints = detectSURFFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectSURFFeatures(app.target_image_data_grey);
                case "KAZE"
                    app.referencePoints = detectKAZEFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectKAZEFeatures(app.target_image_data_grey);
            end
            
            hold(app.ReferenceImage, "on");
            plot(app.referencePoints.selectStrongest(50), app.ReferenceImage);
            hold(app.ReferenceImage, "off");
            
            hold(app.TargetImage, "on");
            plot(app.targetPoints.selectStrongest(50), app.TargetImage);
            hold(app.TargetImage, "off");


        end

        % Menu selected function: CropImageMenu
        function ReferenceCropButtonPushed(app, event)
            app.ToggleWebcamButtonReference.Value = false;
            imshow(app.reference_image_data, 'InitialMagnification', 200);
            roi = drawrectangle;
            try
                app.reference_image_data = imcrop(app.reference_image_data,roi.Position);
            catch ME
                app.reference_image_data = app.reference_image_data;
            end
            close all;
            app.ref_im_object = imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            cla(app.ResultImage);
        end

        % Button pushed function: MatchKeypointsButton
        function MatchKeypointsButtonPushed(app, event)
            [referenceFeatures, app.referencePoints] = extractFeatures(app.reference_image_data_grey, app.referencePoints);
            [targetFeatures, app.targetPoints] = extractFeatures(app.target_image_data_grey, app.targetPoints);
            
            
            pairs = matchFeatures(referenceFeatures, targetFeatures);
            matchedReferencePoints = app.referencePoints(pairs(:, 1), :);
            matchedTargetPoints = app.targetPoints(pairs(:, 2), :);
            
            
            if((matchedTargetPoints.length > 2) && (matchedReferencePoints.length > 2))
                
                
                if(app.estimateGeometricTransformCheckBox.Value == 0)
                showMatchedFeatures(app.reference_image_data, app.target_image_data, matchedReferencePoints, ...
                matchedTargetPoints, 'montage', "Parent", app.ResultImage);
                return;
                end
                
                [tform, estimatedReferencePoints, estimatedTargetPoints] = ...
                    estimateGeometricTransform(matchedReferencePoints, matchedTargetPoints, 'affine');
                
                foundPolygon = [1, 1;...                                                                % top-left
                            size(app.reference_image_data, 2), 1;...                                    % top-right
                            size(app.reference_image_data, 2), size(app.reference_image_data, 1);...    % bottom-right
                            1, size(app.reference_image_data, 1);...                                    % bottom-left
                            1, 1];                                                                      % top-left again to close the polygon
            
                newFoundPolygon = transformPointsForward(tform, foundPolygon);
                imshow(app.target_image_data, 'Parent', app.ResultImage);
                hold(app.ResultImage, "on");
                line(app.ResultImage, newFoundPolygon(:, 1), newFoundPolygon(:, 2), 'Color', 'y');
                hold(app.ResultImage, "off");
                
                % TODO display this
                % showMatchedFeatures(app.reference_image_data, app.target_image_data, estimatedReferencePoints, ...
                %    estimatedTargetPoints, 'montage');
                % hold(app.ResultImage, "off");
            else
                msgbox("Not enough features found for matching.");
            end
            
            
        end

        % Value changed function: ToggleWebcamButtonTarget
        function WebcamToggleTarget(app, event)
            tmp = handle_webcam(app, app.TargetImage, event.Value);
            if(isempty(tmp))
                return;
            else
               app.target_image_data = tmp;
            end
            
        end

        % Value changed function: ToggleWebcamButtonReference
        function WebcamToggleReference(app, event)
            tmp = handle_webcam(app, app.ReferenceImage, event.Value);
            if(isempty(tmp))
                return;
            else
               app.reference_image_data = tmp;
            end
        end

        % Menu selected function: SaveImageMenu
        function SaveReferenceButtonPushed(app, event)
            if(~isempty(app.reference_image_data))
                imsave(image(app.reference_image_data));
            end
        end

        % Menu selected function: SaveImageMenu_2
        function SaveTargetButtonPushed(app, event)
            if(~isempty(app.target_image_data))
               imsave(image(app.target_image_data));
            end
        end

        % Menu selected function: CropImageMenu_2
        function TargetCropButtonPushed(app, event)
            app.ToggleWebcamButtonTarget.Value = false;
            imshow(app.target_image_data, 'InitialMagnification', 200);
            roi = drawrectangle;
            try
                app.target_image_data = imcrop(app.target_image_data,roi.Position);
            catch ME
                app.target_image_data = app.target_image_data;
            end
            close all;
            imshow(app.target_image_data,'Parent',app.TargetImage);
            cla(app.ResultImage);
        end

        % Button pushed function: ReferenceImageMenuButton
        function ReferenceImageMenuButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.DetectionMethodDropDownLabel = uilabel(app.UIFigure);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.UIFigure);
            app.DetectionMethodDropDown.Items = {'BRISK', 'FAST', 'Harris', 'MinEigen', 'ORB', 'SURF', 'KAZE'};
            app.DetectionMethodDropDown.Value = 'BRISK';
            app.estimateGeometricTransformCheckBox = uicheckbox(app.UIFigure);
            app.estimateGeometricTransformCheckBox.Text = 'estimateGeometricTransform';
            app.FindKeypointsButton = uibutton(app.UIFigure, 'push');
            app.FindKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindKeypointsButtonPushed, true);
            app.FindKeypointsButton.Text = 'Find Keypoints';
            app.ReferenceImage = uiaxes(app.UIFigure);
            title(app.ReferenceImage, {''; ''; ''})
            xlabel(app.ReferenceImage, {''; ''})
            ylabel(app.ReferenceImage, {''; ''})
            app.ReferenceImage.XTick = [];
            app.ReferenceImage.YTick = [];
            app.TargetImage = uiaxes(app.UIFigure);
            title(app.TargetImage, {''; ''; ''})
            xlabel(app.TargetImage, {''; ''})
            ylabel(app.TargetImage, {''; ''})
            app.TargetImage.XTick = [];
            app.TargetImage.YTick = [];
            app.ResultImage = uiaxes(app.UIFigure);
            title(app.ResultImage, {''; ''; ''})
            xlabel(app.ResultImage, {''; ''})
            ylabel(app.ResultImage, {''; ''})
            app.ResultImage.XTick = [];
            app.ResultImage.YTick = [];
            app.MatchKeypointsButton = uibutton(app.UIFigure, 'push');
            app.MatchKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @MatchKeypointsButtonPushed, true);
            app.MatchKeypointsButton.Text = 'Match Keypoints';
            app.ToggleWebcamButtonTarget = uibutton(app.UIFigure, 'state');
            app.ToggleWebcamButtonTarget.ValueChangedFcn = createCallbackFcn(app, @WebcamToggleTarget, true);
            app.ToggleWebcamButtonTarget.Text = 'Toggle Webcam';
            app.ToggleWebcamButtonReference = uibutton(app.UIFigure, 'state');
            app.ToggleWebcamButtonReference.ValueChangedFcn = createCallbackFcn(app, @WebcamToggleReference, true);
            app.ToggleWebcamButtonReference.Text = 'Toggle Webcam';
            app.ReferenceImageMenuButton = uibutton(app.UIFigure, 'push');
            app.ReferenceImageMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceImageMenuButtonPushed, true);
            app.ReferenceImageMenuButton.Text = 'Reference Image Menu';
            app.TargetImageMenuButton = uibutton(app.UIFigure, 'push');
            app.TargetImageMenuButton.Text = 'Target Image Menu';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'ninetydegree');
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2Label = uilabel(app.UIFigure);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.ReferenceImageMenuButton.ContextMenu = app.ContextMenu;
            app.SelectImageMenu = uimenu(app.ContextMenu);
            app.SelectImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectReferenceButtonPushed, true);
            app.SelectImageMenu.Text = 'Select Image';
            app.SaveImageMenu = uimenu(app.ContextMenu);
            app.SaveImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveReferenceButtonPushed, true);
            app.SaveImageMenu.Text = 'Save Image';
            app.CropImageMenu = uimenu(app.ContextMenu);
            app.CropImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ReferenceCropButtonPushed, true);
            app.CropImageMenu.Text = 'Crop Image';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu2
            app.TargetImageMenuButton.ContextMenu = app.ContextMenu2;
            app.SelectImageMenu_2 = uimenu(app.ContextMenu2);
            app.SelectImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SelectTargetButtonPushed, true);
            app.SelectImageMenu_2.Text = 'Select Image';
            app.SaveImageMenu_2 = uimenu(app.ContextMenu2);
            app.SaveImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SaveTargetButtonPushed, true);
            app.SaveImageMenu_2.Text = 'Save Image';
            app.CropImageMenu_2 = uimenu(app.ContextMenu2);
            app.CropImageMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TargetCropButtonPushed, true);
            app.CropImageMenu_2.Text = 'Crop Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end